classdef ElevatorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Elevator01     matlab.ui.control.Image
        Elevator02     matlab.ui.control.Image
        Line01         matlab.ui.control.Label
        Line02         matlab.ui.control.Label
        Line03         matlab.ui.control.Label
        Line04         matlab.ui.control.Label
        ButtonUp01     matlab.ui.control.StateButton
        ButtonUp02     matlab.ui.control.StateButton
        ButtonDown02   matlab.ui.control.StateButton
        ButtonDown03   matlab.ui.control.StateButton
        Panel1         matlab.ui.container.Panel
        E1Floor03      matlab.ui.control.StateButton
        E1Floor02      matlab.ui.control.StateButton
        E1Floor01      matlab.ui.control.StateButton
        E1Open         matlab.ui.control.StateButton
        E1Close        matlab.ui.control.StateButton
        Panel2         matlab.ui.container.Panel
        E2Floor03      matlab.ui.control.StateButton
        E2Floor02      matlab.ui.control.StateButton
        E2Floor01      matlab.ui.control.StateButton
        E2Open         matlab.ui.control.StateButton
        E2Close        matlab.ui.control.StateButton
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        StopButton     matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        sf % Description
    end
    
    properties (Access = public)
        floorEle1 = 1;
        dirEle1 = 'stop';
        floorEle2 = 1;
        dirEle2 = 'stop';
        RequestDB1
        RequestDB2
        timeEle1
        timeEle2
    end
    
    methods (Access = public)
        function floornow = getfloorEle1(app)
            position = app.Elevator01.Position(2);
            app.floorEle1 = (position-100)/200;
            floornow = app.floorEle1; 
        end
        
        function floornow = getfloorEle2(app)
            position = app.Elevator02.Position(2);
            app.floorEle2 = (position-100)/200;
            floornow = app.floorEle2; 
        end
        
        function Sort1(app,id)
            if id == 1
                dir = app.dirEle1;
                floornow = app.getfloorEle1;
                reqDB = app.RequestDB1;
            elseif id == 2
                dir = app.dirEle2;
                floornow = app.getfloorEle2;
                reqDB = app.RequestDB2;
            end
%             app.TextArea.Value = '1';
            if (strcmp(dir,'up'))
                if floornow>=1 && floornow<2
%                     app.TextArea.Value = '2';
                    for i = 1:length(reqDB.requestList)
                        if reqDB.requestList(i).destination == 2
                            temp = reqDB.requestList(i);
                            reqDB.delete(reqDB.requestList(i));
                            reqDB.addfront(temp);
                            app.RequestDB1 = reqDB;
%                             app.TextArea.Value = 'change'; 
                        end
                    end
                end
            elseif (strcmp(dir,'down'))
                if floornow>2 && floornow<=3
                    for i = 1:length(reqDB.requestList)
                        if reqDB.requestList(i).destination == 2
                            temp = reqDB.requestList(i);
                            reqDB.delete(reqDB.requestList(i));
                            reqDB.addfront(temp);
                            app.RequestDB1 = reqDB;
                            break
                        end
                    end
                end
            end
            
        end
        
        function Sort2(app,id)
            if id == 1
                dir = app.dirEle1;
                floornow = app.getfloorEle1;
                reqDB = app.RequestDB1;
            elseif id == 2
                dir = app.dirEle2;
                floornow = app.getfloorEle2;
                reqDB = app.RequestDB2;
            end
%             app.TextArea.Value = '1';
            if (strcmp(dir,'up'))
                if floornow>=1 && floornow<2
%                     app.TextArea.Value = '2';
                    for i = 1:length(reqDB.requestList)
                        if reqDB.requestList(i).destination == 2
                            temp = reqDB.requestList(i);
                            reqDB.delete(reqDB.requestList(i));
                            reqDB.addfront(temp);
                            app.RequestDB2 = reqDB;
%                             app.TextArea.Value = 'change'; 
                        end
                    end
                end
            elseif (strcmp(dir,'down'))
                if floornow>2 && floornow<=3
                    for i = 1:length(reqDB.requestList)
                        if reqDB.requestList(i).destination == 2
                            temp = reqDB.requestList(i);
                            reqDB.delete(reqDB.requestList(i));
                            reqDB.addfront(temp);
                            app.RequestDB2 = reqDB;
                            break
                        end
                    end
                end
            end
        end
        
        function Pick(app,req)
            floor1 = app.getfloorEle1;
            floor2 = app.getfloorEle2;
            dist1 = floor1 - req.destination;
            dist2 = floor2 - req.destination;
            if (req.destination == 2) & ~strcmp(app.dirEle1, req.direction) & ~strcmp(app.dirEle1,'stop')
                dir1 = 100;
            else
                dir1 = 0;
            end
            if (req.destination == 2) & ~strcmp(app.dirEle2, req.direction) & ~strcmp(app.dirEle2,'stop')
                dir2 = 100;
            else
                dir2 = 0;
            end
            door1 = 0;
            door2 = 0;
            if strcmp(app.Elevator01.ImageSource,'open.png')
                door1 = 10;
            end
            if strcmp(app.Elevator02.ImageSource,'open.png')
                door2 = 10;
            end
            if (req.destination == 1)& ~strcmp(app.dirEle1, 'down') & ~strcmp(app.dirEle1,'stop')
                dir1 = 100;
            end
            if (req.destination == 3)& ~strcmp(app.dirEle1, 'up') & ~strcmp(app.dirEle1,'stop')
                dir1 = 100;
            end
            if (req.destination == 1)& ~strcmp(app.dirEle2, 'down') & ~strcmp(app.dirEle2,'stop')
                dir2 = 100;
            end
            if (req.destination == 3)& ~strcmp(app.dirEle2, 'up') & ~strcmp(app.dirEle2,'stop')
                dir2 = 100;
            end
            f1 = dir1 + abs(dist1)+door1;
            f2 = dir2 + abs(dist2)+door2;
            app.TextArea.Value = [num2str(f1),' ',num2str(f2)];
            if f1 <= f2
                req.device = 'floor';
                app.RequestDB1.registerFloor(req);
            else
                req.device = 'floor';
                app.RequestDB2.registerFloor(req);
            end
        end
        
        function MoveEle1(app)
            
            if (~isempty(app.RequestDB1.requestList))
                app.TextArea.Value = 'aaa';
                floornow = app.getfloorEle1;
%                 app.floorEle1 = floornow;
%                 app.TextArea.Value = num2str(floornow);
                if (app.RequestDB1.requestList(1).destination > floornow) & ~strcmp(app.Elevator01.ImageSource,'open.png')
                    app.TextArea.Value = 'a';
                    app.Sort1(1);
                    app.dirEle1 = 'up';
                    app.Elevator01.Position(2) = app.Elevator01.Position(2)+40;
                elseif app.RequestDB1.requestList(1).destination < floornow & ~strcmp(app.Elevator01.ImageSource,'open.png')
                    app.TextArea.Value = 'a';
                    app.Sort1(1);
                    app.dirEle1 = 'down';
                    app.Elevator01.Position(2) = app.Elevator01.Position(2)-40;
                else
                    app.dirEle1 = 'stop';
                    app.TextArea.Value = app.RequestDB1.requestList(1).status;
                    if (strcmp(app.RequestDB1.requestList(1).status,'open'))
                        app.TextArea.Value = 'c';
                        a = datestr(now);
                        s = str2num(a(end - 1 : end));
                        min = str2num(a(end - 4 : end - 3));
                        h = str2num(a(end - 7 : end - 6));

                        time = s + min * 60 + h * 3600;
                        app.TextArea.Value = num2str(time);
%                         time - app.RequestDB1.requestList(1).time
                        if time - app.RequestDB1.requestList(1).time >= 5
                            
                            app.CloseDoor01;
                        end
                    else
                        app.TextArea.Value = 'b';
                        req = Request;
                        req.device = 'ele1';
                        req.destination = floornow;
                        req.status = 'open';
                        req.CalculateTime;
                        app.RequestDB1.addfront(req);
                        app.Elevator01.ImageSource = 'open.png';
                    end
                    
                    
                end
                
            end
        end


        function MoveEle2(app)
            
            if (~isempty(app.RequestDB2.requestList))
                
                floornow = app.getfloorEle2;
%                 app.floorEle2 = floornow;
%                 app.TextArea.Value = num2str(floornow);
                if (app.RequestDB2.requestList(1).destination > floornow) & ~strcmp(app.Elevator02.ImageSource,'open.png')
%                     app.TextArea.Value = 'a';
                    app.Sort2(2);
                    app.dirEle2 = 'up';
                    app.Elevator02.Position(2) = app.Elevator02.Position(2)+40;
                elseif app.RequestDB2.requestList(1).destination < floornow & ~strcmp(app.Elevator02.ImageSource,'open.png')
%                     app.TextArea.Value = 'a';
                    app.Sort2(2);
                    app.dirEle2 = 'down';
                    app.Elevator02.Position(2) = app.Elevator02.Position(2)-40;
                else
                    app.dirEle2 = 'stop';
%                     app.TextArea.Value = app.RequestDB2.requestList(1).status;
                    if (strcmp(app.RequestDB2.requestList(1).status,'open'))
%                         app.TextArea.Value = 'c';
                        a = datestr(now);
                        s = str2num(a(end - 1 : end));
                        min = str2num(a(end - 4 : end - 3));
                        h = str2num(a(end - 7 : end - 6));

                        time = s + min * 60 + h * 3600;
                        app.TextArea.Value = num2str(time);
%                         time - app.RequestDB1.requestList(1).time
                        if time - app.RequestDB2.requestList(1).time >= 5
                            
                            app.CloseDoor02;
                        end
                    else
%                         app.TextArea.Value = 'b';
                        req = Request;
                        req.device = 'ele2';
                        req.destination = floornow;
                        req.status = 'open';
                        req.CalculateTime;
                        app.RequestDB2.addfront(req);
                        app.Elevator02.ImageSource = 'open.png';
                    end
                end  
            end
        end
        
        function CloseDoor01(app)
            if strcmp(app.Elevator01.ImageSource, 'open.png')
                floornow = app.getfloorEle1;
                dir = app.dirEle1;
                
                remove = [];
                if floornow == 2
                    app.TextArea.Value = num2str(length(app.RequestDB1.requestList));
                    for i = 1:length(app.RequestDB1.requestList)
                        if (app.RequestDB1.requestList(i).destination == floornow)
                            if strcmp(app.RequestDB1.requestList(i).direction,'up')
                                if strcmp(dir,'up')&&strcmp(dir,'stop')
                                    remove = [remove; i]; 
                                    app.RequestDB1.requestList(i).button.Icon = app.RequestDB1.requestList(i).icon;
                                end
                            end
%                             app.TextArea.Value = num2str(i);
                            remove = [remove; i];
                            app.RequestDB1.requestList(i).button.Icon = app.RequestDB1.requestList(i).icon;
                        end
                    end
                else
                    for i = 1:length(app.RequestDB1.requestList)  
                        if (app.RequestDB1.requestList(i).destination == floornow)
                            remove = [remove; i];
                            app.RequestDB1.requestList(i).button.Icon = app.RequestDB1.requestList(i).icon;
                        end
                    end                    
                end
                app.RequestDB1.requestList(remove) = [];
            end
            app.Elevator01.ImageSource = 'close.png';
        end
        
        function CloseDoor02(app)
            if strcmp(app.Elevator02.ImageSource, 'open.png')
                floornow = app.getfloorEle2;
                dir = app.dirEle2;
                app.Elevator02.ImageSource = 'close.png'; 
                remove = [];
                if floornow == 2
                    app.TextArea.Value = num2str(length(app.RequestDB2.requestList));
                    for i = 1:length(app.RequestDB2.requestList)
                        if (app.RequestDB2.requestList(i).destination == floornow)
                            if strcmp(app.RequestDB2.requestList(i).direction,'up')
                                if strcmp(dir,'up')&&strcmp(dir,'stop')
                                    remove = [remove; i]; 
                                    app.RequestDB2.requestList(i).button.Icon = app.RequestDB2.requestList(i).icon;
                                end
                            end
%                             app.TextArea.Value = num2str(i);
                            remove = [remove; i];
                            app.RequestDB2.requestList(i).button.Icon = app.RequestDB2.requestList(i).icon;
                        end
                    end
                else
                    for i = 1:length(app.RequestDB2.requestList)  
                        if (app.RequestDB2.requestList(i).destination == floornow)
                            remove = [remove; i];
                            app.RequestDB2.requestList(i).button.Icon = app.RequestDB2.requestList(i).icon;
                        end
                    end                    
                end
                app.RequestDB2.requestList(remove) = [];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.sf = ElevatorController('app', app);
        end

        % Value changed function: ButtonUp01
        function ButtonUp01ValueChanged(app, event)
            req = Request;
            req.destination = 1;
            req.status = 'working';
            req.direction = 'up';
            req.button = app.ButtonUp01;
            req.icon = 'up_press_no.png';
            req.CalculateTime;
            app.Pick(req);
            app.ButtonUp01.Icon = 'up_press_yes.png';
        end

        % Value changed function: E1Floor03
        function E1Floor03ValueChanged(app, event)
%             value = app.E1Floor03.Value;
            req = Request;
            req.device = 'ele1';
            req.destination = 3;
            req.status = 'working';
            req.direction = 'up';
            req.button = app.E1Floor03;
            req.CalculateTime;
            app.RequestDB1.register(req);
            req.icon = 'floor3_no.png';
            app.E1Floor03.Icon = 'floor3_yes.png';
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            if value == 1
                stop(app.timeEle1);
                stop(app.timeEle2);
                app.StopButton.Text = 'Resume';
            else
                start(app.timeEle1);
                start(app.timeEle2);
                app.StopButton.Text = 'Stop';
            end
        end

        % Value changed function: E1Floor02
        function E1Floor02ValueChanged(app, event)
%             value = app.E1Floor02.Value;
            req = Request;
            req.device = 'ele1';
            req.destination = 2;
            req.status = 'working';
            req.button = app.E1Floor02;
            req.CalculateTime;
            app.RequestDB1.register(req);
            req.icon = 'floor2_no.png';
            app.E1Floor02.Icon = 'floor2_yes.png';
        end

        % Value changed function: E1Floor01
        function E1Floor01ValueChanged(app, event)
%             value = app.E1Floor01.Value;
            req = Request;
            req.device = 'ele1';
            req.destination = 1;
            req.status = 'working';
            req.button = app.E1Floor01;
            req.CalculateTime;
            app.RequestDB1.register(req);
            req.icon = 'floor1_no.png';
            app.E1Floor01.Icon = 'floor1_yes.png';
        end

        % Value changed function: E2Floor03
        function E2Floor03ValueChanged(app, event)
%             value = app.E2Floor03.Value;
            req = Request;
            req.device = 'ele2';
            req.destination = 3;
            req.status = 'working';
            req.button = app.E2Floor03;
            req.CalculateTime;
            app.RequestDB2.register(req);
            req.icon = 'floor3_no.png';
            app.E2Floor03.Icon = 'floor3_yes.png';
        end

        % Value changed function: E2Floor02
        function E2Floor02ValueChanged(app, event)
%             value = app.E2Floor02.Value;
            req = Request;
            req.device = 'ele2';
            req.destination = 2;
            req.status = 'working';
            req.button = app.E2Floor02;
            req.CalculateTime;
            app.RequestDB2.register(req);
            req.icon = 'floor2_no.png';
            app.E2Floor02.Icon = 'floor2_yes.png';
        end

        % Value changed function: E2Floor01
        function E2Floor01ValueChanged(app, event)
%             value = app.E2Floor01.Value;
            req = Request;
            req.device = 'ele2';
            req.destination = 1;
            req.status = 'working';
            req.button = app.E2Floor01;
            req.CalculateTime;
            app.RequestDB2.register(req);
            req.icon = 'floor1_no.png';
            app.E2Floor01.Icon = 'floor1_yes.png';
        end

        % Value changed function: ButtonUp02
        function ButtonUp02ValueChanged(app, event)
            req = Request;
            req.destination = 2;
            req.status = 'working';
            req.direction = 'up';
            req.button = app.ButtonUp02;
            req.CalculateTime;
            app.Pick(req);
            req.icon = 'up_press_no.png';
            app.ButtonUp02.Icon = 'up_press_yes.png';
        end

        % Value changed function: ButtonDown02
        function ButtonDown02ValueChanged(app, event)
            req = Request;
            req.destination = 2;
            req.status = 'working';
            req.direction = 'down';
            req.button = app.ButtonDown02;
            req.CalculateTime;
            app.Pick(req);
            req.icon = 'down_press_no.png';
            app.ButtonDown02.Icon = 'down_press_yes.png';
        end

        % Value changed function: ButtonDown03
        function ButtonDown03ValueChanged(app, event)
%             value = app.ButtonDown03.Value;
            req = Request;
            req.destination = 3;
            req.status = 'working';
            req.direction = 'down';
            req.button = app.ButtonDown03;
            req.CalculateTime;
            app.Pick(req);
            req.icon = 'down_press_no.png';
            app.ButtonDown03.Icon = 'down_press_yes.png';
        end

        % Value changed function: E1Open
        function E1OpenValueChanged(app, event)
            dir = app.dirEle1;
            if strcmp(dir,'stop')
                req = Request;
                req.device = 'ele1';
                req.destination = app.getfloorEle1;
                req.status = 'open';
                req.button = app.E1Open;
                req.CalculateTime;
                app.RequestDB1.addfront(req);
                app.Elevator01.ImageSource = 'open.png';
                req.icon = 'open_button_no.png';
                app.E1Open.Icon = 'open_button_yes.png';
            end
        end

        % Value changed function: E2Open
        function E2OpenValueChanged(app, event)
%             value = app.E2Open.Value;
            dir = app.dirEle2;
            if strcmp(dir,'stop')
                req = Request;
                req.device = 'ele1';
                req.destination = app.getfloorEle2;
                req.status = 'open';
                req.button = app.E2Open;
                req.CalculateTime;
                app.RequestDB2.addfront(req);
                app.Elevator02.ImageSource = 'open.png';
                req.icon = 'open_button_no.png';
                app.E2Open.Icon = 'open_button_yes.png';
            end
        end

        % Value changed function: E1Close
        function E1CloseValueChanged(app, event)
%             value = app.E1Close.Value;
            app.CloseDoor01;
            app.E1Close.Icon = 'close_button_yes.png';
            pause(0.2);
            app.E1Close.Icon = 'close_button_no.png';
        end

        % Value changed function: E2Close
        function E2CloseValueChanged(app, event)
%             value = app.E2Close.Value;
            app.CloseDoor02;
            app.E2Close.Icon = 'close_button_yes.png';
            pause(0.2);
            app.E2Close.Icon = 'close_button_no.png';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Elevator01 = uiimage(app.UIFigure);
            app.Elevator01.ImageSource = 'close.png';
            app.Elevator02 = uiimage(app.UIFigure);
            app.Elevator02.ImageSource = 'close.png';
            app.Line01 = uilabel(app.UIFigure);
            app.Line01.Text = {'--------------------------------------------------------------------------------------------'; ''};
            app.Line02 = uilabel(app.UIFigure);
            app.Line02.Text = {'--------------------------------------------------------------------------------------------'; ''};
            app.Line03 = uilabel(app.UIFigure);
            app.Line03.Text = {'--------------------------------------------------------------------------------------------'; ''};
            app.Line04 = uilabel(app.UIFigure);
            app.Line04.Text = {'--------------------------------------------------------------------------------------------'; ''};
            app.ButtonUp01 = uibutton(app.UIFigure, 'state');
            app.ButtonUp01.ValueChangedFcn = createCallbackFcn(app, @ButtonUp01ValueChanged, true);
            app.ButtonUp01.Icon = 'up_press_no.png';
            app.ButtonUp01.Text = '';
            app.ButtonUp02 = uibutton(app.UIFigure, 'state');
            app.ButtonUp02.ValueChangedFcn = createCallbackFcn(app, @ButtonUp02ValueChanged, true);
            app.ButtonUp02.Icon = 'up_press_no.png';
            app.ButtonUp02.Text = '';
            app.ButtonDown02 = uibutton(app.UIFigure, 'state');
            app.ButtonDown02.ValueChangedFcn = createCallbackFcn(app, @ButtonDown02ValueChanged, true);
            app.ButtonDown02.Icon = 'down_press_no.png';
            app.ButtonDown02.Text = '';
            app.ButtonDown03 = uibutton(app.UIFigure, 'state');
            app.ButtonDown03.ValueChangedFcn = createCallbackFcn(app, @ButtonDown03ValueChanged, true);
            app.ButtonDown03.Icon = 'down_press_no.png';
            app.ButtonDown03.Text = '';
            app.Panel1 = uipanel(app.UIFigure);
            app.E1Floor03 = uibutton(app.Panel1, 'state');
            app.E1Floor03.ValueChangedFcn = createCallbackFcn(app, @E1Floor03ValueChanged, true);
            app.E1Floor03.Icon = 'floor3_no.png';
            app.E1Floor03.Text = {''; ''};
            app.E1Floor02 = uibutton(app.Panel1, 'state');
            app.E1Floor02.ValueChangedFcn = createCallbackFcn(app, @E1Floor02ValueChanged, true);
            app.E1Floor02.Icon = 'floor2_no.png';
            app.E1Floor02.Text = {''; ''};
            app.E1Floor01 = uibutton(app.Panel1, 'state');
            app.E1Floor01.ValueChangedFcn = createCallbackFcn(app, @E1Floor01ValueChanged, true);
            app.E1Floor01.Icon = 'floor1_no.png';
            app.E1Floor01.Text = {''; ''};
            app.E1Open = uibutton(app.Panel1, 'state');
            app.E1Open.ValueChangedFcn = createCallbackFcn(app, @E1OpenValueChanged, true);
            app.E1Open.Icon = 'open_button_no.png';
            app.E1Open.Text = {''; ''};
            app.E1Close = uibutton(app.Panel1, 'state');
            app.E1Close.ValueChangedFcn = createCallbackFcn(app, @E1CloseValueChanged, true);
            app.E1Close.Icon = 'close_button_no.png';
            app.E1Close.Text = {''; ''};
            app.Panel2 = uipanel(app.UIFigure);
            app.E2Floor03 = uibutton(app.Panel2, 'state');
            app.E2Floor03.ValueChangedFcn = createCallbackFcn(app, @E2Floor03ValueChanged, true);
            app.E2Floor03.Icon = 'floor3_no.png';
            app.E2Floor03.Text = {''; ''};
            app.E2Floor02 = uibutton(app.Panel2, 'state');
            app.E2Floor02.ValueChangedFcn = createCallbackFcn(app, @E2Floor02ValueChanged, true);
            app.E2Floor02.Icon = 'floor2_no.png';
            app.E2Floor02.Text = {''; ''};
            app.E2Floor01 = uibutton(app.Panel2, 'state');
            app.E2Floor01.ValueChangedFcn = createCallbackFcn(app, @E2Floor01ValueChanged, true);
            app.E2Floor01.Icon = 'floor1_no.png';
            app.E2Floor01.Text = {''; ''};
            app.E2Open = uibutton(app.Panel2, 'state');
            app.E2Open.ValueChangedFcn = createCallbackFcn(app, @E2OpenValueChanged, true);
            app.E2Open.Icon = 'open_button_no.png';
            app.E2Open.Text = {''; ''};
            app.E2Close = uibutton(app.Panel2, 'state');
            app.E2Close.ValueChangedFcn = createCallbackFcn(app, @E2CloseValueChanged, true);
            app.E2Close.Icon = 'close_button_no.png';
            app.E2Close.Text = {''; ''};
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevatorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end