classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TabGroup      matlab.ui.container.TabGroup
        Tab           matlab.ui.container.Tab
        StartButton   matlab.ui.control.Button
        Image         matlab.ui.control.Image
        Exit          matlab.ui.control.Button
        Tab2          matlab.ui.container.Tab
        ButtonGroup   matlab.ui.container.ButtonGroup
        Button_11     matlab.ui.control.ToggleButton
        Button_12     matlab.ui.control.ToggleButton
        Button_13     matlab.ui.control.ToggleButton
        Button_14     matlab.ui.control.ToggleButton
        Button_15     matlab.ui.control.ToggleButton
        Button_16     matlab.ui.control.ToggleButton
        Button_17     matlab.ui.control.ToggleButton
        Button_18     matlab.ui.control.ToggleButton
        Button_19     matlab.ui.control.ToggleButton
        Button_20     matlab.ui.control.ToggleButton
        Button_21     matlab.ui.control.ToggleButton
        Button_22     matlab.ui.control.ToggleButton
        Image2        matlab.ui.control.Image
        Button_23     matlab.ui.control.Button
        Tab3          matlab.ui.container.Tab
        UIAxes        matlab.ui.control.UIAxes
        Panel         matlab.ui.container.Panel
        right         matlab.ui.control.Button
        up            matlab.ui.control.Button
        left          matlab.ui.control.Button
        down          matlab.ui.control.Button
        Panel_2       matlab.ui.container.Panel
        GridLayout    matlab.ui.container.GridLayout
        Button        matlab.ui.control.StateButton
        Button_3      matlab.ui.control.StateButton
        Button_5      matlab.ui.control.StateButton
        Button_6      matlab.ui.control.StateButton
        Button_7      matlab.ui.control.StateButton
        Button_8      matlab.ui.control.StateButton
        Button_9      matlab.ui.control.StateButton
        Button_10     matlab.ui.control.StateButton
        Button_4      matlab.ui.control.StateButton
        Button_2      matlab.ui.control.StateButton
        UndoButton    matlab.ui.control.Button
        SuccessLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
%         Chess % Description
        currentchoose = [];
        currentno;
        Engine;
        historychess = [];
        historyno = [];
        historymove = [];
        opening = 0;
        
    end
    
    methods (Access = private)
        
        function clickevent(app, Button, no)
            app.Panel.Visible = Button.Value;
            if(~isempty(app.currentchoose))
                if(app.currentchoose(1) ~= Button)
                    app.currentchoose(1).Value = 0;
                    app.currentchoose = [Button];
                    app.currentno = no;
                else
                    app.currentchoose = [];
                    app.currentno = -1;
                end
            else
                app.currentchoose = [Button];
                app.currentno = no;
            end
            
            
        end
        
        function suc = undo(app, no, dir, button)
            if dir == 1
                [suc, ~, ~] = app.Engine.moveChess(no, 'down');
                button.Layout.Row = button.Layout.Row + 1;
            elseif dir == 2
                [suc, ~, ~] = app.Engine.moveChess(no, 'up');
                button.Layout.Row = button.Layout.Row - 1;
            elseif dir == 3
                [suc, ~, ~] = app.Engine.moveChess(no, 'right');
                button.Layout.Column = button.Layout.Column + 1;
            elseif dir == 4
                [suc, ~, ~] = app.Engine.moveChess(no, 'left');
                button.Layout.Column = button.Layout.Column - 1;
            end
        end
        
        function init_ChessBoard(app)
            
            [x, y] = find(app.Engine.chessBoard == 1, 4);
            x = [x(1), x(2)];
            y = [y(2) + 1, y(3) + 1];
            
            if x(1) > x(2)
                x = [x(2), x(1)];
            end
            
            if y(1) > y(2)
                y = [y(2), y(1)];
            end
            
            app.Button.Layout.Row = x;
            app.Button.Layout.Column = y;
            
            
            [x, y] = find(app.Engine.chessBoard == 2, 2);
            x = [x(1), x(2)];
            y = [y(1) + 1, y(2) + 1];
            if x(1) == x(2)
                if y(1) > y(2)
                    y = [y(2), y(1)];
                end
                app.Button_2.Icon = 'huangzhong_heng.png';
                app.Button_2.Layout.Row = [x(1)];
                app.Button_2.Layout.Column = y;
            else
                if x(1) > x(2)
                    x = [x(2), x(1)];
                end
                app.Button_2.Icon = 'huangzhogn.jpg';
                app.Button_2.Layout.Row = x;
                app.Button_2.Layout.Column = [y(1)];
            end
            
            [x, y] = find(app.Engine.chessBoard == 3, 2);
            x = [x(1), x(2)];
            y = [y(1) + 1, y(2) + 1];
            if x(1) == x(2)
                if y(1) > y(2)
                    y = [y(2), y(1)];
                end
                app.Button_3.Icon = 'jiangwei_heng.png';
                app.Button_3.Layout.Row = [x(1)];
                app.Button_3.Layout.Column = y;
            else
                if x(1) > x(2)
                    x = [x(2), x(1)];
                end
                app.Button_3.Icon = 'jiangwei.jpg';
                app.Button_3.Layout.Row = x;
                app.Button_3.Layout.Column = [y(1)];
            end
            
            [x, y] = find(app.Engine.chessBoard == 4, 2);
            x = [x(1), x(2)];
            y = [y(1) + 1, y(2) + 1];
            if x(1) == x(2)
                if y(1) > y(2)
                    y = [y(2), y(1)];
                end
                app.Button_4.Icon = 'guanyu.jpg';
                app.Button_4.Layout.Row = [x(1)];
                app.Button_4.Layout.Column = y;
            else
                if x(1) > x(2)
                    x = [x(2), x(1)];
                end
                app.Button_4.Icon = 'guanyu_shu.png';
                app.Button_4.Layout.Row = x;
                app.Button_4.Layout.Column = [y(1)];
            end
            
            [x, y] = find(app.Engine.chessBoard == 5, 2);
            x = [x(1), x(2)];
            y = [y(1) + 1, y(2) + 1];
            if x(1) == x(2)
                if y(1) > y(2)
                    y = [y(2), y(1)];
                end
                app.Button_5.Icon = 'machao_heng.png';
                app.Button_5.Layout.Row = [x(1)];
                app.Button_5.Layout.Column = y;
            else
                if x(1) > x(2)
                    x = [x(2), x(1)];
                end
                app.Button_5.Icon = 'machao.jpg';
                app.Button_5.Layout.Row = x;
                app.Button_5.Layout.Column = [y(1)];
            end
            
            [x, y] = find(app.Engine.chessBoard == 6, 2);
            x = [x(1), x(2)];
            y = [y(1) + 1, y(2) + 1];
            if x(1) == x(2)
                if y(1) > y(2)
                    y = [y(2), y(1)];
                end
                app.Button_6.Icon = 'zhangfei_heng.png';
                app.Button_6.Layout.Row = [x(1)];
                app.Button_6.Layout.Column = y;
            else
                if x(1) > x(2)
                    x = [x(2), x(1)];
                end
                app.Button_6.Icon = 'zhangfei.jpg';
                app.Button_6.Layout.Row = x;
                app.Button_6.Layout.Column = [y(1)];
            end
            
            [x, y] = find(app.Engine.chessBoard == 7, 1);
            y = y + 1;
            app.Button_7.Layout.Row = [x];
            app.Button_7.Layout.Column = [y];
            
            [x, y] = find(app.Engine.chessBoard == 8, 1);
            y = y + 1;
            app.Button_8.Layout.Row = [x];
            app.Button_8.Layout.Column = [y];
            
            [x, y] = find(app.Engine.chessBoard == 9, 1);
            y = y + 1;
            app.Button_9.Layout.Row = [x];
            app.Button_9.Layout.Column = [y];
            
            [x, y] = find(app.Engine.chessBoard == 10, 1);
            y = y + 1;
            app.Button_10.Layout.Row = [x];
            app.Button_10.Layout.Column = [y];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            app.clickevent(app.Button, 1);
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            app.clickevent(app.Button_2, 2);
        end

        % Value changed function: Button_3
        function Button_33ValueChanged(app, event)
            app.clickevent(app.Button_3, 3);
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            app.clickevent(app.Button_5, 5);
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            app.clickevent(app.Button_6, 6);
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            app.clickevent(app.Button_4, 4);
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            app.clickevent(app.Button_7, 7);
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            app.clickevent(app.Button_8, 8);
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            app.clickevent(app.Button_9, 9);
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            app.clickevent(app.Button_10, 10);
        end

        % Button pushed function: up
        function upButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'up');
            if a
                app.currentchoose(1).Layout.Row = app.currentchoose(1).Layout.Row - 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 1;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end
            
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'right');
            if a
                app.currentchoose(1).Layout.Column = app.currentchoose(1).Layout.Column + 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 4;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end
        end

        % Button pushed function: down
        function downButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'down');
            if a
                app.currentchoose(1).Layout.Row = app.currentchoose(1).Layout.Row + 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 2;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end

        end

        % Button pushed function: left
        function leftButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'left');
            if a
                app.currentchoose(1).Layout.Column = app.currentchoose(1).Layout.Column - 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 3;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if ~isempty(app.historychess())
                app.undo(app.historyno(end), app.historymove(end), app.historychess(end));
                app.historychess(end) = [];
                app.historymove(end) = [];
                app.historyno(end) = [];
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case'经典模式'
                    app.opening = 0;
                    
                case'随机开局'
                    app.opening = 1;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            app.UIAxes.CurrentPoint;
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab3;
            if app.opening == 1
                app.Engine.randomBoard();
            else
                app.Engine.initBoard();
            end
            
            app.init_ChessBoard();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '开始游戏';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'huarongdao.png';
            app.Exit = uibutton(app.Tab, 'push');
            app.Exit.Text = '退出';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_11 = uitogglebutton(app.ButtonGroup);
            app.Button_11.Text = '经典模式';
            app.Button_11.Value = true;
            app.Button_12 = uitogglebutton(app.ButtonGroup);
            app.Button_12.Enable = 'off';
            app.Button_12.Visible = 'off';
            app.Button_12.Text = '齐头并进';
            app.Button_13 = uitogglebutton(app.ButtonGroup);
            app.Button_13.Enable = 'off';
            app.Button_13.Visible = 'off';
            app.Button_13.Text = '左右布兵';
            app.Button_14 = uitogglebutton(app.ButtonGroup);
            app.Button_14.Enable = 'off';
            app.Button_14.Visible = 'off';
            app.Button_14.Text = '捷足先登';
            app.Button_15 = uitogglebutton(app.ButtonGroup);
            app.Button_15.Enable = 'off';
            app.Button_15.Visible = 'off';
            app.Button_15.Text = '指挥若定';
            app.Button_16 = uitogglebutton(app.ButtonGroup);
            app.Button_16.Enable = 'off';
            app.Button_16.Visible = 'off';
            app.Button_16.Text = '兵分三路';
            app.Button_17 = uitogglebutton(app.ButtonGroup);
            app.Button_17.Enable = 'off';
            app.Button_17.Visible = 'off';
            app.Button_17.Text = '桃花园中';
            app.Button_18 = uitogglebutton(app.ButtonGroup);
            app.Button_18.Enable = 'off';
            app.Button_18.Visible = 'off';
            app.Button_18.Text = '插翅难飞';
            app.Button_19 = uitogglebutton(app.ButtonGroup);
            app.Button_19.Enable = 'off';
            app.Button_19.Visible = 'off';
            app.Button_19.Text = '将拥曹营';
            app.Button_20 = uitogglebutton(app.ButtonGroup);
            app.Button_20.Enable = 'off';
            app.Button_20.Visible = 'off';
            app.Button_20.Text = '雨声淅沥';
            app.Button_21 = uitogglebutton(app.ButtonGroup);
            app.Button_21.Enable = 'off';
            app.Button_21.Visible = 'off';
            app.Button_21.Text = '一路进军';
            app.Button_22 = uitogglebutton(app.ButtonGroup);
            app.Button_22.Text = '随机开局';
            app.Image2 = uiimage(app.Tab2);
            app.Image2.ImageSource = 'choose.png';
            app.Button_23 = uibutton(app.Tab2, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '开始游戏';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes = uiaxes(app.Tab3);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 4];
            app.UIAxes.YLim = [0 5];
            app.UIAxes.Visible = 'off';
            app.Panel = uipanel(app.Tab3);
            app.right = uibutton(app.Panel, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = {'→'; ''};
            app.up = uibutton(app.Panel, 'push');
            app.up.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.up.Text = '↑';
            app.left = uibutton(app.Panel, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.left.Text = '←';
            app.down = uibutton(app.Panel, 'push');
            app.down.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.down.Text = '↓';
            app.Panel_2 = uipanel(app.Tab3);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.Button = uibutton(app.GridLayout, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'caocao.jpg';
            app.Button.Text = {''; ''};
            app.Button.Value = true;
            app.Button_3 = uibutton(app.GridLayout, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_33ValueChanged, true);
            app.Button_3.Icon = 'jiangwei.jpg';
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Icon = 'machao.jpg';
            app.Button_5.Text = {''; ''};
            app.Button_6 = uibutton(app.GridLayout, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Icon = 'zhangfei.jpg';
            app.Button_6.Text = {''; ''};
            app.Button_7 = uibutton(app.GridLayout, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Icon = 'bing.jpg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Icon = 'bing.jpg';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Icon = 'bing.jpg';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.GridLayout, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Icon = 'bing.jpg';
            app.Button_10.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Icon = 'guanyu.jpg';
            app.Button_4.Text = {''; ''};
            app.Button_2 = uibutton(app.GridLayout, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'huangzhogn.jpg';
            app.Button_2.Text = {''; ''};
            app.UndoButton = uibutton(app.Tab3, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = {'Undo'; ''};
            app.SuccessLabel = uilabel(app.Tab3);
            app.SuccessLabel.Visible = 'off';
            app.SuccessLabel.Text = 'Success';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end